classdef GaitAssessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ViewDataButton     matlab.ui.control.Button
        CalibrateButton    matlab.ui.control.Button
        GaitAssessorLabel  matlab.ui.control.Label
        StartButton        matlab.ui.control.Button
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

             
            arduino=serialport("/dev/cu.usbserial-1420",921600);%14120 for noor, 1420 for grace
             
            fopen(arduino);
             
            disp('arduino connected successfully')
            x=(1:1000);
            
            align = fscanf(arduino, '%d');
            disp('reading data...')
            
            for i=1:length(x)
            
	            y(i) = 100*(fscanf(arduino, '%d'));
             
                z(i) = 90*(fscanf(arduino, '%d'));
            
                k(i) = (fscanf(arduino, '%d'));
            
               
            end
            
     
            %plot(app.UIAxes,x,y,'filled',x,z,'filled',x,k,'filled')
            %legend ('Heal strike', 'Toe strike', 'Ankle Angle')
            %disp('done')

            disp('making plot..')
        
            scatter(app.UIAxes,x,y,'filled')
            hold(app.UIAxes,'on')
            scatter(app.UIAxes,x,z,'filled')
            hold(app.UIAxes,'on')
            scatter(app.UIAxes,x,k, 'filled')
            hold(app.UIAxes,'off')
            axis(app.UIAxes,"auto");
            legend (app.UIAxes,'Heal strike', 'Toe strike', 'Ankle Angle')
            disp('done')
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            arduino=serialport("/dev/cu.usbserial-1420",921600);%14120 for noor, 1420 for grace
            fopen(arduino); % initiate arduino communication
            answer = 1 %telling esp32 to calibrate, assuming it sends 0 otherwise
            fprintf(arduino,'%d',answer); % send answer to arduino
            fclose(arduino); % end communication with arduino
        end

        % Button pushed function: ViewDataButton
        function ViewDataButtonPushed(app, event)
            sample_length = 1000;

            fileID = fopen('TypicalGait.txt','r');
            y = zeros(1, sample_length);
            z = zeros(1, sample_length);
            k = zeros(1, sample_length);
            x = (1:sample_length);
        
            sizeA = [1 1];
          
            for j=1:length(x)
                y(j) = fscanf(fileID,'%d', sizeA);
              
                z(j) = fscanf(fileID,'%d', sizeA);
              
                k(j) = fscanf(fileID,'%d',sizeA);
            
            end
            fclose(fileID);
            disp('making plot..')
            scatter(app.UIAxes2,x,y,'filled')
            hold(app.UIAxes2,'on')
            scatter(app.UIAxes2,x,z,'filled')
            hold(app.UIAxes2,'on')
            scatter(app.UIAxes2,x,k, 'filled')
            hold(app.UIAxes2,'off')
            legend (app.UIAxes2,'Heal strike', 'Toe strike', 'Ankle Angle')
            disp('done')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Your Gait Pattern')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1000];
            app.UIAxes.YLim = [0 200];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Typical Gait Pattern')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 1000];
            app.UIAxes2.YLim = [0 200];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.GaitAssessorLabel = uilabel(app.UIFigure);
            app.GaitAssessorLabel.Text = 'Gait Assessor';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.ViewDataButton = uibutton(app.UIFigure, 'push');
            app.ViewDataButton.ButtonPushedFcn = createCallbackFcn(app, @ViewDataButtonPushed, true);
            app.ViewDataButton.Text = 'View Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GaitAssessor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end